classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MQTTstateLamp               matlab.ui.control.Lamp
        MQTTstateLampLabel          matlab.ui.control.Label
        ONButton                    matlab.ui.control.StateButton
        savecodeButton              matlab.ui.control.Button
        recievedcodeEditField       matlab.ui.control.EditField
        recievedcodeEditFieldLabel  matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        ListBoxLabel                matlab.ui.control.Label
        savetaskButton              matlab.ui.control.Button
        deleteButton                matlab.ui.control.Button
        TaskListBox                 matlab.ui.control.ListBox
        TaskListBoxLabel            matlab.ui.control.Label
        CodeListBox                 matlab.ui.control.ListBox
        CodeListBoxLabel            matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        arduinoObj % Serial arduino object
    end
    
    methods (Access = public)
        
        function readTimeData(app,src,~)

            % Read the ASCII data from the serialport object.
            data = readline(src);

            % Use string replace to remove white space.
            data = strrep(data,' ',''); 

            % Received the code that has been decoded into hex code.
            if numel(regexp(data,"Code")) > 0 
                 code = split(data,":");
                 code = code(2);
                 app.recievedcodeEditField.Value = code;
                 callTask(app,code,src.UserData.Dict,src.UserData.MQTT);
            
            % The frist line of timing data has the word "rawData".
            elseif numel(regexp(data,"rawData")) > 0
                % split the unwanted string.
                first_data = split(data,"rawData");
                first_data = first_data(2);
            
                % concatenate all the data.
                src.UserData.Data = src.UserData.Data + first_data;
            
            % The last line of timing data has the word "Sum".
            elseif numel(regexp(data,"Sum")) > 0
                % After received all of the timing data, split them into array.
                timing_data = split(src.UserData.Data,",");
            
                % Clear UserData for receive the next data.
                src.UserData.Data = "";
            
                % Split timing data agian and its become 2D array.
                timing_data = split(timing_data,"+");
                disp(timing_data);
            
                % Change the header of IR signal to be a fixed time.
                timing_data(1,1) = 2000;
            
                % Convert data type from string to double.
                timing_data = str2double(timing_data);
                
                % Get sum of all timing data.
                N = split(data,"Sum:");
                N = str2double(N(2));
                disp(N);
            
                % plot the IR signal graph.
                infraredPlot(app,timing_data,N);
            
            % Concatenate all of the timing data.
            else
                 src.UserData.Data = src.UserData.Data + data;
            
            end % end if
        end % end function
        
        function infraredPlot(app,data,N)
            % Create array of start from 0 to N(sum of timing data).
            t= linspace(0,N,N) ;
            
            % u is rectangular function.
            u = @(t,t0) (t - t0 >= 0);
            
            % v is all of the IR signal function.
            v =  0 ;
            
            % delta is like pointer for creating a rectangular pulse.
            delta = 0 ;
            
            % for loop all the timing data to create rectangular pulse.
            for i=1 : (numel(data))/2
                v = v + u(t, delta) - u(t,(delta+abs(data(i,1))));
                delta = delta + abs(data(i,1)) + abs(data(i,2));
            end % end for
            
            % plot the graph.
            plot(app.UIAxes,t,v,'b','linewidth',2);
            axis(app.UIAxes,[-1000 N+500 -0.5 1.5]);
            
            end % end function
        
        function callTask(app,code,Dict,MQTT)

            if isKey(Dict,code)
                disp(Dict(code));
                write(MQTT, "zigbee2mqtt/0x00158d0003532089/set", "TOGGLE");
            else
                disp("No task");
            
            end % end if
            end % end function

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arduinoObj = serialport("COM3",115200);
            configureTerminator(app.arduinoObj,"CR/LF");
            flush(app.arduinoObj);
            code_dict = dictionary(string([]),[]);
            MyMQTT = mqttclient("tcp://192.168.127.163:1883");
            app.arduinoObj.UserData = struct("Data","","Dict",code_dict,"MQTT",MyMQTT);
            configureCallback(app.arduinoObj,"terminator",@(src,event) readTimeData(app,src,event));
            app.arduinoObj.UserData.Dict("E21DFF00") = 1;
        end

        % Button pushed function: savecodeButton
        function savecodeButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IR PULSE SIGNAL')
            xlabel(app.UIAxes, 'Time(microsec)')
            ylabel(app.UIAxes, 'Logic')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-0.5 10];
            app.UIAxes.YLim = [-0.5 3.5];
            app.UIAxes.YTick = [0 1];
            app.UIAxes.YTickLabel = {'0'; '1'};
            app.CodeListBoxLabel = uilabel(app.UIFigure);
            app.CodeListBoxLabel.Text = 'Code';
            app.CodeListBox = uilistbox(app.UIFigure);
            app.TaskListBoxLabel = uilabel(app.UIFigure);
            app.TaskListBoxLabel.Text = 'Task:';
            app.TaskListBox = uilistbox(app.UIFigure);
            app.deleteButton = uibutton(app.UIFigure, 'push');
            app.deleteButton.Text = 'delete';
            app.savetaskButton = uibutton(app.UIFigure, 'push');
            app.savetaskButton.Text = 'save task';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Code: abcd -----> "ON"'};
            app.ListBox.Value = 'Code: abcd -----> "ON"';
            app.recievedcodeEditFieldLabel = uilabel(app.UIFigure);
            app.recievedcodeEditFieldLabel.Text = 'recieved code';
            app.recievedcodeEditField = uieditfield(app.UIFigure, 'text');
            app.savecodeButton = uibutton(app.UIFigure, 'push');
            app.savecodeButton.ButtonPushedFcn = createCallbackFcn(app, @savecodeButtonPushed, true);
            app.savecodeButton.Text = 'save code';
            app.ONButton = uibutton(app.UIFigure, 'state');
            app.ONButton.Text = 'ON';
            app.MQTTstateLampLabel = uilabel(app.UIFigure);
            app.MQTTstateLampLabel.Text = 'MQTT state:';
            app.MQTTstateLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end